classdef ImageAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        LoadimageButton          matlab.ui.control.StateButton
        Channel1CheckBox         matlab.ui.control.CheckBox
        Channel2CheckBox         matlab.ui.control.CheckBox
        Channel3CheckBox         matlab.ui.control.CheckBox
        ColocalizationDropDown1  matlab.ui.control.DropDown
        ColocalizationDropDown2  matlab.ui.control.DropDown
        CellMaskCheckBox         matlab.ui.control.CheckBox
        SegmentDropDown          matlab.ui.control.DropDown
        SegmentCellLabel         matlab.ui.control.Label
        LoadMaskButton           matlab.ui.control.Button
        SegmentSlider            matlab.ui.control.Slider
        WatershedButton          matlab.ui.control.Button
        LoadImageLabel           matlab.ui.control.Label
        AnalyzeLabel             matlab.ui.control.Label
        SegmentThresholdButton   matlab.ui.control.Button
        SaveMaskButton           matlab.ui.control.Button
        SaveAnalysisButton       matlab.ui.control.Button
        LevelLabel2              matlab.ui.control.Label
        ApproximateButton        matlab.ui.control.Button
        SegmentSigma             matlab.ui.control.Slider
        LevelLabel2_2            matlab.ui.control.Label
        SegmentRadius            matlab.ui.control.Slider
        LevelLabel2_3            matlab.ui.control.Label
        SeparateButton           matlab.ui.control.Button
        Region1EditFieldLabel    matlab.ui.control.Label
        Region1EditField         matlab.ui.control.NumericEditField
        Region2EditFieldLabel    matlab.ui.control.Label
        Region2EditField         matlab.ui.control.NumericEditField
        RegionsLabel             matlab.ui.control.Label
        MakeRegionsButton        matlab.ui.control.Button
        RegionsCheckBox          matlab.ui.control.CheckBox
        Amount                   matlab.ui.control.Slider
        LevelLabel2_4            matlab.ui.control.Label
        RefineButton             matlab.ui.control.Button
        ClippingplaneLabel       matlab.ui.control.Label
        SliderX                  matlab.ui.control.Slider
        LevelLabel2_5            matlab.ui.control.Label
        SliderY                  matlab.ui.control.Slider
        LevelLabel2_6            matlab.ui.control.Label
        SliderZ                  matlab.ui.control.Slider
        LevelLabel2_7            matlab.ui.control.Label
        SliderR                  matlab.ui.control.Slider
        LevelLabel2_8            matlab.ui.control.Label
        PlaneCheckBox            matlab.ui.control.CheckBox
        CropButton               matlab.ui.control.Button
        FlipButton               matlab.ui.control.Button
        PTPlaneButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        ZSliderLabel             matlab.ui.control.Label
        ZSlider                  matlab.ui.control.Slider
    end

    properties (Access = private)
        C1 % channel 1
        C2 % channel 2
        C3 % channel 3
        voxelSize %voxel size
        cellMask % binary image of the cell
        colocalization % colocalization value
        currentfile %current file
        region1
        region2
        region3
        flip
        planeX1
    end


    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            app.C1 = [];
            app.C2 = [];
            app.C3 = [];
            app.cellMask = [];
            app.region1 = [];
            app.region2 = [];
            app.region3 = [];
            app.flip = 1;
        end

        % Button pushed function: ApproximateButton
        function ApproximateButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                app.cellMask = approximatecell(app.C1,app.SegmentRadius.Value);
                app.showImage();
            end
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            plane = makeplane3(app.C1,app.SliderX.Value,app.SliderY.Value,app.SliderZ.Value,app.SliderR.Value,app.flip);
            app.C1 = app.C1.*plane;
            app.C2 = app.C2.*plane;
            app.C3 = app.C3.*plane;
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            app.flip = -1*app.flip;
        end

        % Button pushed function: MakeRegionsButton
        function MakeRegionsButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                [app.region1,app.region2,app.region3] = makeregions(app.cellMask,app.Region1EditField.Value,app.Region2EditField.Value,app.voxelSize);
                app.showImage();
            end
        end

        % Value changed function: PlaneCheckBox
        function PlaneCheckBoxValueChanged(app, event)
            app.showImage();
            
        end

        % Button pushed function: RefineButton
        function RefineButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                app.cellMask = refinemask(app.cellMask,app.Amount.Value);
                app.showImage();
            end
        end

        % Value changed function: RegionsCheckBox
        function RegionsCheckBoxValueChanged(app, event)
            app.showImage();
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate regions first');
            else
                if(app.ColocalizationDropDown1.Value == 'Channel 1')
                    C4 = app.C1;
                    name1 = 'C1';
                elseif(app.ColocalizationDropDown1.Value == 'Channel 2')
                    C4 = app.C2;
                    name1 = 'C2';
                else
                    C4 = app.C3;
                    name1 = 'C2';
                end
                if(app.ColocalizationDropDown2.Value == 'Channel 1')
                    C5 = app.C1;
                    name2 = 'C1';
                elseif(app.ColocalizationDropDown2.Value == 'Channel 2')
                    C5 = app.C2;
                    name2 = 'C2';
                else
                    C5 = app.C3;
                    name2 = 'C3';
                end
                T = analyzeregions(C4,C5,app.cellMask,app.region1,app.region2,app.region3);
                [pathstr,name,ext] = fileparts(app.currentfile);
                writetable(T,strcat('data/analysis',name1,name2,'_',name,'.xls'));
            end
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                savetif1(app.cellMask,strcat('data/mask_',app.currentfile));
            end
        end

        % Button pushed function: SeparateButton
        function SeparateButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                app.cellMask = separatecells(app.cellMask,app.SegmentRadius.Value,app.voxelSize);
                app.showImage();
            end
        end

        % Value changed function: SliderR
        function SliderRValueChanged(app, event)
            app.showImage();
            
        end

        % Value changed function: SliderX
        function SliderXValueChanged(app, event)
            app.showImage();
        end

        % Value changed function: SliderY
        function SliderYValueChanged(app, event)
            app.showImage();
        end

        % Value changed function: SliderZ
        function SliderZValueChanged(app, event)
            app.showImage();
        end

        % Button pushed function: PTPlaneButton
        function ThreePTPlanePushed(app, event)
           msgbox('Please double click one point on the membrane');
            [x1, y1] = getpts(app.UIAxes);
            
            %             msgbox('Please double click one point on the membrane in the same z slice');
            %             [x2, y2] = getpts(app.UIAxes);
            %             msgbox('Please double click one point on the membrane in a DIFFERENT (disparate) z slice');
            %             [x3, y3] = getpts(app.UIAxes);
            x1
            y1
            plane = makeplane3(app.C1,app.SliderX.Value,app.SliderY.Value,app.SliderZ.Value,app.SliderR.Value,app.flip);
            app.C1 = app.C1.*plane;
            app.C2 = app.C2.*plane;
            app.C3 = app.C3.*plane;
        end

        % Button pushed function: WatershedButton
        function WatershedButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Need rough cell mask');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.cellMask = watershedcell(app.C1,app.cellMask,app.SegmentSigma.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.cellMask = watershedcell(app.C2,app.cellMask,app.SegmentSigma.Value);
                else
                    app.cellMask =  watershedcell(app.C3,app.cellMask,app.SegmentSigma.Value);
                end
                app.showImage();
            end
        end

        % Button pushed function: SegmentThresholdButton
        function cellThresholdButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.cellMask = thresholdcell(app.C1,app.SegmentSlider.Value,app.SegmentSigma.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.cellMask = thresholdcell(app.C2,app.SegmentSlider.Value,app.SegmentSigma.Value);
                else
                    app.cellMask = thresholdcell(app.C3,app.SegmentSlider.Value,app.SegmentSigma.Value);
                end
                app.showImage();
            end
        end

        % Value changed function: LoadimageButton
        function loadImageButtonPushed(app, event)
            filename = uigetfile('*.tif');
            app.currentfile = filename;
            app.currentfile
            [app.C1,app.C2,app.C3,app.voxelSize] = loadtif3(app.currentfile);
            app.ZSlider.Limits = [1 size(app.C1,3)];
            app.CellMaskCheckBox.Value = 0;
            app.RegionsCheckBox.Value = 0;
            app.cellMask = [];
            app.region1 = [];
            app.region2 = [];
            app.region3 = [];
            app.LoadimageButton.Value = 0;
            app.showImage();
        end

        % Button pushed function: LoadMaskButton
        function loadMaskButtonPushed(app, event)
            filename = uigetfile('*.tif');
            app.cellMask = loadtif1(filename);
            app.showImage();
        end

        % Callback function: CellMaskCheckBox, Channel1CheckBox, 
        % Channel2CheckBox, Channel3CheckBox, ZSlider
        function showImage(app, event)
            z = round(app.ZSlider.Value);
            if(app.Channel1CheckBox.Value == 1)
                R = app.C1(:,:,z);
            else
                R = app.C1(:,:,1).*0;
            end
            if(app.Channel2CheckBox.Value == 1)
                G = app.C2(:,:,z);
            else
                G = app.C2(:,:,1).*0;
            end
            if(app.Channel3CheckBox.Value == 1)
                B = app.C3(:,:,z);
            else
                B = app.C3(:,:,1).*0;
            end
            if(app.CellMaskCheckBox.Value == 1)
                if(isempty(app.cellMask))
                    msgbox('Calculate cell mask first');
                else
                    R = max(R,bwperim(app.cellMask(:,:,z)));
                    G = max(G,bwperim(app.cellMask(:,:,z)));
                    B = max(B,bwperim(app.cellMask(:,:,z)));
                end
            end
            if(app.RegionsCheckBox.Value == 1)
                if(isempty(app.region1))
                    msgbox('Calculate regions first');
                else
                    R = max(R,bwperim(app.region2(:,:,z)));
                    G = max(G,bwperim(app.region2(:,:,z)));
                    B = max(B,bwperim(app.region2(:,:,z)));
                end
            end
            if(app.PlaneCheckBox.Value == 1)
                plane = makeplane2(R,z,app.SliderX.Value,app.SliderY.Value,app.SliderZ.Value,app.SliderR.Value,app.flip);
                R = R.*plane;
                G = G.*plane;
                B = B.*plane;
            end
            % Jonah UIAxes and makeplane2
            im = image(app.UIAxes,cat(3,R,G,B));
% Shows the developer was just lazy
            %             im.Parent.Parent.KeyReleaseFcn = {@imageButtonClick, app, im};
            app.planeX1
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.LoadimageButton = uibutton(app.Panel, 'state');
            app.LoadimageButton.ValueChangedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.Channel1CheckBox = uicheckbox(app.Panel);
            app.Channel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel1CheckBox.Text = 'Channel 1';
            app.Channel1CheckBox.Value = true;
            app.Channel2CheckBox = uicheckbox(app.Panel);
            app.Channel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel2CheckBox.Text = 'Channel 2';
            app.Channel2CheckBox.Value = true;
            app.Channel3CheckBox = uicheckbox(app.Panel);
            app.Channel3CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.Channel3CheckBox.Text = 'Channel 3';
            app.Channel3CheckBox.Value = true;
            app.ColocalizationDropDown1 = uidropdown(app.Panel);
            app.ColocalizationDropDown1.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColocalizationDropDown1.Value = 'Channel 1';
            app.ColocalizationDropDown2 = uidropdown(app.Panel);
            app.ColocalizationDropDown2.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColocalizationDropDown2.Value = 'Channel 1';
            app.CellMaskCheckBox = uicheckbox(app.Panel);
            app.CellMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.CellMaskCheckBox.Text = 'Cell Mask';
            app.SegmentDropDown = uidropdown(app.Panel);
            app.SegmentDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.SegmentDropDown.Value = 'Channel 1';
            app.SegmentCellLabel = uilabel(app.Panel);
            app.SegmentCellLabel.Text = 'Segment Cell';
            app.LoadMaskButton = uibutton(app.Panel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @loadMaskButtonPushed, true);
            app.LoadMaskButton.Text = 'Load Mask';
            app.SegmentSlider = uislider(app.Panel);
            app.SegmentSlider.Limits = [0 1];
            app.SegmentSlider.MinorTicks = [];
            app.SegmentSlider.Value = 0.5;
            app.WatershedButton = uibutton(app.Panel, 'push');
            app.WatershedButton.ButtonPushedFcn = createCallbackFcn(app, @WatershedButtonPushed, true);
            app.WatershedButton.Text = 'Watershed';
            app.LoadImageLabel = uilabel(app.Panel);
            app.LoadImageLabel.Text = 'Load Image';
            app.AnalyzeLabel = uilabel(app.Panel);
            app.AnalyzeLabel.Text = 'Analyze';
            app.SegmentThresholdButton = uibutton(app.Panel, 'push');
            app.SegmentThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @cellThresholdButtonPushed, true);
            app.SegmentThresholdButton.Text = 'Threshold';
            app.SaveMaskButton = uibutton(app.Panel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.SaveAnalysisButton = uibutton(app.Panel, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.LevelLabel2 = uilabel(app.Panel);
            app.LevelLabel2.Text = 'Level';
            app.ApproximateButton = uibutton(app.Panel, 'push');
            app.ApproximateButton.ButtonPushedFcn = createCallbackFcn(app, @ApproximateButtonPushed, true);
            app.ApproximateButton.Text = 'Approximate';
            app.SegmentSigma = uislider(app.Panel);
            app.SegmentSigma.Limits = [0 10];
            app.SegmentSigma.MinorTicks = [];
            app.SegmentSigma.Value = 5;
            app.LevelLabel2_2 = uilabel(app.Panel);
            app.LevelLabel2_2.Text = 'Sigma';
            app.SegmentRadius = uislider(app.Panel);
            app.SegmentRadius.Limits = [0 1];
            app.SegmentRadius.MinorTicks = [];
            app.SegmentRadius.Value = 0.5;
            app.LevelLabel2_3 = uilabel(app.Panel);
            app.LevelLabel2_3.Text = 'Radius';
            app.SeparateButton = uibutton(app.Panel, 'push');
            app.SeparateButton.ButtonPushedFcn = createCallbackFcn(app, @SeparateButtonPushed, true);
            app.SeparateButton.Text = 'Separate';
            app.Region1EditFieldLabel = uilabel(app.Panel);
            app.Region1EditFieldLabel.Text = 'Region 1';
            app.Region1EditField = uieditfield(app.Panel, 'numeric');
            app.Region2EditFieldLabel = uilabel(app.Panel);
            app.Region2EditFieldLabel.Text = 'Region 2';
            app.Region2EditField = uieditfield(app.Panel, 'numeric');
            app.RegionsLabel = uilabel(app.Panel);
            app.RegionsLabel.Text = 'Regions';
            app.MakeRegionsButton = uibutton(app.Panel, 'push');
            app.MakeRegionsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeRegionsButtonPushed, true);
            app.MakeRegionsButton.Text = 'Make Regions';
            app.RegionsCheckBox = uicheckbox(app.Panel);
            app.RegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegionsCheckBoxValueChanged, true);
            app.RegionsCheckBox.Text = 'Regions';
            app.Amount = uislider(app.Panel);
            app.Amount.Limits = [-5 5];
            app.Amount.MinorTicks = [];
            app.LevelLabel2_4 = uilabel(app.Panel);
            app.LevelLabel2_4.Text = 'Amount';
            app.RefineButton = uibutton(app.Panel, 'push');
            app.RefineButton.ButtonPushedFcn = createCallbackFcn(app, @RefineButtonPushed, true);
            app.RefineButton.Text = 'Refine';
            app.ClippingplaneLabel = uilabel(app.Panel);
            app.ClippingplaneLabel.Text = 'Clipping plane';
            app.SliderX = uislider(app.Panel);
            app.SliderX.Limits = [-1 1];
            app.SliderX.MajorTicks = [];
            app.SliderX.MajorTickLabels = {};
            app.SliderX.ValueChangedFcn = createCallbackFcn(app, @SliderXValueChanged, true);
            app.SliderX.MinorTicks = [];
            app.LevelLabel2_5 = uilabel(app.Panel);
            app.LevelLabel2_5.Text = 'X';
            app.SliderY = uislider(app.Panel);
            app.SliderY.Limits = [-1 1];
            app.SliderY.MajorTicks = [];
            app.SliderY.MajorTickLabels = {};
            app.SliderY.ValueChangedFcn = createCallbackFcn(app, @SliderYValueChanged, true);
            app.SliderY.MinorTicks = [];
            app.LevelLabel2_6 = uilabel(app.Panel);
            app.LevelLabel2_6.Text = 'Y';
            app.SliderZ = uislider(app.Panel);
            app.SliderZ.Limits = [-2 2];
            app.SliderZ.MajorTicks = [];
            app.SliderZ.MajorTickLabels = {};
            app.SliderZ.ValueChangedFcn = createCallbackFcn(app, @SliderZValueChanged, true);
            app.SliderZ.MinorTicks = [];
            app.LevelLabel2_7 = uilabel(app.Panel);
            app.LevelLabel2_7.Text = 'Z';
            app.SliderR = uislider(app.Panel);
            app.SliderR.Limits = [-500 500];
            app.SliderR.MajorTicks = [];
            app.SliderR.MajorTickLabels = {};
            app.SliderR.ValueChangedFcn = createCallbackFcn(app, @SliderRValueChanged, true);
            app.SliderR.MinorTicks = [];
            app.LevelLabel2_8 = uilabel(app.Panel);
            app.LevelLabel2_8.Text = 'R';
            app.PlaneCheckBox = uicheckbox(app.Panel);
            app.PlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlaneCheckBoxValueChanged, true);
            app.PlaneCheckBox.Text = 'Plane';
            app.CropButton = uibutton(app.Panel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.FlipButton = uibutton(app.Panel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.PTPlaneButton = uibutton(app.Panel, 'push');
            app.PTPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @ThreePTPlanePushed, true);
            app.PTPlaneButton.Text = '3 PT Plane';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.MajorTicks = [];
            app.ZSlider.MajorTickLabels = {};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @showImage, true);
            app.ZSlider.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageAnalyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end